classdef GUI_Handler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StaticTab                       matlab.ui.container.Tab
        EnterCellIDsseparatedbyEditFieldLabel  matlab.ui.control.Label
        CellID                          matlab.ui.control.EditField
        EntertestedtemperaturesseparatedbyEditFieldLabel  matlab.ui.control.Label
        temp                            matlab.ui.control.EditField
        EnterminVoltagesforeachcellseparatedbyEditFieldLabel  matlab.ui.control.Label
        minV                            matlab.ui.control.EditField
        EntermaxVoltagesforeachcellseparatedbyEditFieldLabel  matlab.ui.control.Label
        maxV                            matlab.ui.control.EditField
        Simulate                        matlab.ui.control.Button
        LogsTextAreaLabel               matlab.ui.control.Label
        Logs                            matlab.ui.control.TextArea
        Save                            matlab.ui.control.CheckBox
        DynamicTab                      matlab.ui.container.Tab
        EnterCellIDsseparatedbyEditFieldLabel_2  matlab.ui.control.Label
        CellIDDyn                       matlab.ui.control.EditField
        EntertestedtemperaturesseparatedbyEditFieldLabel_2  matlab.ui.control.Label
        tempDyn                         matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        mag                             matlab.ui.control.EditField
        NumberofRCpairsEditFieldLabel   matlab.ui.control.Label
        NumberofRCpairsEditField        matlab.ui.control.NumericEditField
        LogsTextAreaLabel_5             matlab.ui.control.Label
        LogsDyn                         matlab.ui.control.TextArea
        SimulateDyn                     matlab.ui.control.Button
        SaveDyn                         matlab.ui.control.CheckBox
        EVSimTab                        matlab.ui.container.Tab
        LogsTextAreaLabel_3             matlab.ui.control.Label
        EVLogs                          matlab.ui.control.TextArea
        DriveCycleFilesseparatedbyEditFieldLabel  matlab.ui.control.Label
        files                           matlab.ui.control.EditField
        CellParametersLabel             matlab.ui.control.Label
        ModuleParametersLabel           matlab.ui.control.Label
        PackParametersLabel             matlab.ui.control.Label
        MotorParametersLabel            matlab.ui.control.Label
        WheelParametersLabel            matlab.ui.control.Label
        DrivetrainParametersLabel       matlab.ui.control.Label
        VehicleParametersLabel          matlab.ui.control.Label
        CapacityAhEditFieldLabel        matlab.ui.control.Label
        CapacityAhEditField             matlab.ui.control.NumericEditField
        WeightgEditFieldLabel           matlab.ui.control.Label
        WeightgEditField                matlab.ui.control.NumericEditField
        VmaxEditFieldLabel              matlab.ui.control.Label
        VmaxEditField                   matlab.ui.control.NumericEditField
        VnomEditFieldLabel              matlab.ui.control.Label
        VnomEditField                   matlab.ui.control.NumericEditField
        VminEditFieldLabel              matlab.ui.control.Label
        VminEditField                   matlab.ui.control.NumericEditField
        NoParallelEditFieldLabel        matlab.ui.control.Label
        NoParallelEditField             matlab.ui.control.NumericEditField
        NoSeriesEditFieldLabel          matlab.ui.control.Label
        NoSeriesEditField               matlab.ui.control.NumericEditField
        OverHeadEditFieldLabel          matlab.ui.control.Label
        OverHeadEditField               matlab.ui.control.NumericEditField
        NoSeriesEditField_2Label        matlab.ui.control.Label
        NoSeriesEditField_2             matlab.ui.control.NumericEditField
        OverHeadEditField_2Label        matlab.ui.control.Label
        OverHeadEditField_2             matlab.ui.control.NumericEditField
        FullSOCEditFieldLabel           matlab.ui.control.Label
        FullSOCEditField                matlab.ui.control.NumericEditField
        EmptySOCEditFieldLabel          matlab.ui.control.Label
        EmptySOCEditField               matlab.ui.control.NumericEditField
        EfficiencyEditFieldLabel        matlab.ui.control.Label
        EfficiencyEditField             matlab.ui.control.NumericEditField
        LmaxNmEditFieldLabel            matlab.ui.control.Label
        LmaxNmEditField                 matlab.ui.control.NumericEditField
        RatedRPMEditFieldLabel          matlab.ui.control.Label
        RatedRPMEditField               matlab.ui.control.NumericEditField
        maxRPMEditFieldLabel            matlab.ui.control.Label
        maxRPMEditField                 matlab.ui.control.NumericEditField
        EfficiencyEditField_2Label      matlab.ui.control.Label
        EfficiencyEditField_2           matlab.ui.control.NumericEditField
        InertiaKgm2Label_2              matlab.ui.control.Label
        InertiaKgm2EditField            matlab.ui.control.NumericEditField
        RadiusmEditFieldLabel           matlab.ui.control.Label
        RadiusmEditField                matlab.ui.control.NumericEditField
        InertiaKgm2Label                matlab.ui.control.Label
        InertiaKgm2EditField_2          matlab.ui.control.NumericEditField
        RollCoeffEditFieldLabel         matlab.ui.control.Label
        RollCoeffEditField              matlab.ui.control.NumericEditField
        InverterEfficiencyEditFieldLabel  matlab.ui.control.Label
        InverterEfficiencyEditField     matlab.ui.control.NumericEditField
        FractionalRegenTorqueEditFieldLabel  matlab.ui.control.Label
        FractionalRegenTorqueEditField  matlab.ui.control.NumericEditField
        GearRatioEditFieldLabel         matlab.ui.control.Label
        GearRatioEditField              matlab.ui.control.NumericEditField
        GearInertiaKgm2EditFieldLabel   matlab.ui.control.Label
        GearInertiaKgm2EditField        matlab.ui.control.NumericEditField
        GearEfficiencyEditFieldLabel    matlab.ui.control.Label
        GearEfficiencyEditField         matlab.ui.control.NumericEditField
        NoofWheelsEditFieldLabel        matlab.ui.control.Label
        NoofWheelsEditField             matlab.ui.control.NumericEditField
        RoadForceEditFieldLabel         matlab.ui.control.Label
        RoadForceEditField              matlab.ui.control.NumericEditField
        CDEditFieldLabel                matlab.ui.control.Label
        CDEditField                     matlab.ui.control.NumericEditField
        FrontAream2Label                matlab.ui.control.Label
        FrontAream2EditField            matlab.ui.control.NumericEditField
        WeightKgEditFieldLabel          matlab.ui.control.Label
        WeightKgEditField               matlab.ui.control.NumericEditField
        PayloadKgEditFieldLabel         matlab.ui.control.Label
        PayloadKgEditField              matlab.ui.control.NumericEditField
        OverheadpowerwEditFieldLabel    matlab.ui.control.Label
        OverheadpowerwEditField         matlab.ui.control.NumericEditField
        EVSimulate                      matlab.ui.control.Button
        CellSimTab                      matlab.ui.container.Tab
        LogsTextAreaLabel_4             matlab.ui.control.Label
        CellLogs                        matlab.ui.control.TextArea
        OCVfromTemperatureandSOCLabel   matlab.ui.control.Label
        TemperatureCEditFieldLabel      matlab.ui.control.Label
        TemperatureCEditField           matlab.ui.control.NumericEditField
        SCOEditFieldLabel               matlab.ui.control.Label
        SCOEditField                    matlab.ui.control.NumericEditField
        ModelnameEditFieldLabel         matlab.ui.control.Label
        ModelnameEditField              matlab.ui.control.EditField
        CalculateButton                 matlab.ui.control.Button
        SCMPCMsimulatorLabel            matlab.ui.control.Label
        NumberofcellsinSeriesEditFieldLabel  matlab.ui.control.Label
        NumberofcellsinSeriesEditField  matlab.ui.control.NumericEditField
        NumberofcellsinParallellEditFieldLabel  matlab.ui.control.Label
        NumberofcellsinParallellEditField  matlab.ui.control.NumericEditField
        SimulationruntimesEditFieldLabel  matlab.ui.control.Label
        SimulationruntimesEditField     matlab.ui.control.NumericEditField
        PackResttimesEditFieldLabel     matlab.ui.control.Label
        PackResttimesEditField          matlab.ui.control.NumericEditField
        TabresistanceEditFieldLabel     matlab.ui.control.Label
        TabresistanceEditField          matlab.ui.control.NumericEditField
        RandomiseinitialSOCCheckBox     matlab.ui.control.CheckBox
        RandomiseinitialcellresistanceCheckBox  matlab.ui.control.CheckBox
        RandomiseinitialcellcapacitanceCheckBox  matlab.ui.control.CheckBox
        SimulateasPCMButton             matlab.ui.control.Button
        SimulateasSCMButton             matlab.ui.control.Button
        LoadsimulatorLabel              matlab.ui.control.Label
        ItwhereisdischargeEditFieldLabel  matlab.ui.control.Label
        ItwhereisdischargeEditField     matlab.ui.control.EditField
        TemperatureCEditField_2Label    matlab.ui.control.Label
        TemperatureCEditField_2         matlab.ui.control.NumericEditField
        SamplingTimesEditFieldLabel     matlab.ui.control.Label
        SamplingTimesEditField          matlab.ui.control.NumericEditField
        ModelnameEditField_2Label       matlab.ui.control.Label
        ModelnameEditField_2            matlab.ui.control.EditField
        RawtestfilenameEditFieldLabel   matlab.ui.control.Label
        RawtestfilenameEditField        matlab.ui.control.EditField
        Z0EditFieldLabel                matlab.ui.control.Label
        Z0EditField                     matlab.ui.control.NumericEditField
        InitialResCurrentEditFieldLabel  matlab.ui.control.Label
        InitialResCurrentEditField      matlab.ui.control.NumericEditField
        InitialHysteresisstateEditFieldLabel  matlab.ui.control.Label
        InitialHysteresisstateEditField  matlab.ui.control.NumericEditField
        SimulateLoadButton              matlab.ui.control.Button
        AboutTab                        matlab.ui.container.Tab
        AbouttheappLabel                matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        iTextBox = 0;
    end
    
    methods (Access = public)
        
        function DisplayLog(app,message)
            app.iTextBox = app.iTextBox + 1;
            app.Logs.Value = [app.Logs.Value;message];
            
        end
        
        function results = DisplayLogEVSim(app,message)
            app.EVLogs.Value = [app.EVLogs.Value;message];
        end
        
        function results = simulateVsim(~,files,cellParameter,moduleParameter,packParameter,motorParameter,wheelParameter,drivetrainParameter,vehicleParameter)
            addpath('ESCToolbox/OCV_Files')
            addpath('ESCToolbox/DYN_Files/')
            addpath('EVsim/')
            addpath('PCMSCM/')
            
            result = setupSimVehicleGUI(files, cellParameter,moduleParameter, packParameter, motorParameter, wheelParameter, drivetrainParameter, vehicleParameter);
        end
        
        function results = DisplayLogCellSim(app,message)
            app.CellLogs.Value = [app.CellLogs.Value;message];
            
        end
        
        function results = getocv(app,soc,temp,modelname)
            addpath('PCMSCM/')
            %cd("ESCToolbox/DYN_Files/")
            load(modelname)
            results = OCVfromSOCtemp(soc,temp,model);
            log = sprintf('expected OCV is : %d',results);
            DisplayLogCellSim(app,log)
            
        end
        
        function results = simPCM(app,Ns,Np,maxtime,t0,rt,randsoc,randcap,randres)
            addpath('PCMSCM/')
            result = simPCMGUI(Ns,Np,maxtime,t0,rt,randsoc,randcap,randres);
            
            
        end
        
        function results = simSCM(app,Ns,Np,maxtime,t0,rt,randsoc,randcap,randres)
            addpath('PCMSCM/')
            result = simSCMGUI(Ns,Np,maxtime,t0,rt,randsoc,randcap,randres);
            
        end
        
        function results = DisplayLogDyn(app,message)
            app.LogsDyn.Value = [app.LogsDyn.Value;message];
        end
        
        function results = GUIDyn(app,cellIDs,temps,mags,numpoles)
            cd("ESCToolbox/DYN_Files/");
            setupGUIDyn(cellIDs,temps,mags,numpoles);
        end
        
        function results = simload(app)
            cd("ESCToolbox/DYN_Files/");
            load E2model.mat;
            load E2_DYN/E2_DYN_35_P25.mat;
            deltaT = 1;
            time = DYNData.script1.time - DYNData.script1.time(1);
            t = (0:deltaT:time(end));
            voltage = interp1(time,DYNData.script1.voltage,t);
            current = interp1(time,DYNData.script1.current,t);
            time = t;
            [vest,rck,hk,zk,sik,OCV] = simCell(current,25,deltaT,model,1,0,0);
            figure;
            subplot(1,2,1)
            plot(time/3600,voltage,time/3600,vest); % factor of 3600 converts seconds -> hours
            xlabel('Time (hr)'); ylabel('Voltage (V)'); title('Comparing measured to simulated voltage');
            legend('Measured voltage','Simulated voltage');
            
            %Now, plot the voltage prediction error
            %figure;
            subplot(1,2,2)
            plot(time/3600,1000*(voltage-vest'));
            xlabel('Time (hr)'); ylabel('Voltage (mV)'); title('Voltage prediction error');
            figure ;
            %Visualize the change in dynamic hysteresis over the test
            subplot(1,2,1); plot(time/3600,hk);
            xlabel('Time (hr)'); ylabel('Dynamic hysteresis state (unitless))'); title('Model prediction of hysteresis state');
            %figure ;
            %Visualize the change in instantaneous hysteresis state over the test
            subplot(1,2,2); plot(time/3600,sik);
            xlabel('Time (hr)'); ylabel('Instantaneous hysteresis state (unitless))'); title('Model prediction of instantaneous hyst.');
            
        end
    end
    
    methods (Access = private)
        
        function results = simulateocv(~,cellIDs,temps,minV,maxV,saveornot)
            addpath('ESCToolbox/OCV_Files')
            addpath('ESCToolbox/DYN_Files/')
            addpath('EVsim/')
            addpath('PCMSCM/')
            cd('ESCToolbox/OCV_Files/')
            result = RunProcessOCVGUI(cellIDs,temps,minV,maxV,saveornot);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Simulate
        function SimulateButtonPushed(app, event)
            
            a = app.CellID.Value;
            b=erase(a,{'[',']'});
            cellIDs=split(b,{' ',','});
            
            a = app.temp.Value;
            temps=str2double(strsplit(a,','));
            %b=erase(a,{'[',']'});
            %temps=(split(b,[' ',',']));
            
            
            a = app.minV.Value;
            minVs=str2double(strsplit(a,','));
            %b=erase(a,{'[',']'});
            %minVs=(split(b,{' ',','}));
            
            
            a = app.maxV.Value;
            maxVs=str2double(strsplit(a,','));
            %b=erase(a,{'[',']'});
            %maxVs=(split(b,{' ',','}));
            
            a=0;
            
            saveornot = app.Save.Value;
            
            log = sprintf('you have entered cell IDs as');
            DisplayLog(app,log)
            log = sprintf('  %s ',cellIDs{:});
            DisplayLog(app,log)
            
            log = sprintf('you have entered temp values as');
            DisplayLog(app,log)
            log = sprintf('  %d ',temps());
            DisplayLog(app,log)
            
            log = sprintf('you have entered min voltages as');
            DisplayLog(app,log)
            log = sprintf('  %d ',minVs());
            DisplayLog(app,log)
            
            log = sprintf('you have entered max voltages as');
            DisplayLog(app,log)
            log = sprintf('  %d ',maxVs());
            DisplayLog(app,log)


            if( max(size(cellIDs)) ~= max(size(minVs))  || max(size(maxVs)) ~= max(size(minVs)) )
                log = sprintf('you have entered incorrect number of data( no. of Cell IDs , Min Voltages and Max Voltages should be same)');
                DisplayLog(app,log)
            else
                %%simulate
                %minVs = str2double(minVs);
                %minVs = str2double(minVs);
                %temps= str2double(temps);
                ftemps = {};
                for i = 1:length(cellIDs)
                    ftemps{i} = temps;
                end
                
                simulateocv(app,cellIDs,ftemps,minVs,maxVs,saveornot)
            end
            
            
        end

        % Button pushed function: EVSimulate
        function EVSimulateButtonPushed(app, event)
            log = sprintf('Starting EV sim, check command window for results');
            DisplayLogEVSim(app,log)
            
            
            a = app.files.Value;
            b=erase(a,{'[',']'});
            files =split(b,{' ',','});


            cellParameter.capacity =app.CapacityAhEditField.Value ;
            cellParameter.weight =app.WeightgEditField.Value ;
            cellParameter.vmax =app.VmaxEditField.Value ;
            cellParameter.vnom =app.VnomEditField.Value ;
            cellParameter.vmin = app.VminEditField.Value;
            
            moduleParameter.numParallel = app.NoParallelEditField.Value ;
            moduleParameter.numSeries =app.NoSeriesEditField.Value ;
            moduleParameter.overHead =app.OverHeadEditField.Value ;
            
            packParameter.numSeries = app.NoSeriesEditField_2.Value;
            packParameter.overHead = app.OverHeadEditField_2.Value;
            packParameter.fullsoc =app.FullSOCEditField.Value ;
            packParameter.emptysoc =app.EmptySOCEditField.Value ;
            packParameter.efficiency = app.EfficiencyEditField.Value;
            
            motorParameter.lmax = app.LmaxNmEditField.Value;
            motorParameter.ratedRPM = app.RatedRPMEditField.Value;
            motorParameter.maxRPM = app.maxRPMEditField.Value;
            motorParameter.efficiency = app.EfficiencyEditField_2.Value;
            motorParameter.inertia =app.InertiaKgm2EditField.Value ;
            
            wheelParameter.radius = app.RadiusmEditField.Value;
            wheelParameter.inertia =app.InertiaKgm2EditField_2.Value ;
            wheelParameter.rollCoeff =app.RollCoeffEditField.Value;
            
            drivetrainParameter.invertereff = app.InverterEfficiencyEditField.Value;
            drivetrainParameter.fracregen = app.FractionalRegenTorqueEditField.Value;
            drivetrainParameter.gearratio = app.GearRatioEditField.Value;
            drivetrainParameter.gearinertia = app.GearInertiaKgm2EditField.Value;
            drivetrainParameter.gearefficiency =app.GearEfficiencyEditField.Value ;
            
            
            vehicleParameter.wheels =app.NoofWheelsEditField.Value ;
            vehicleParameter.roadForce = app.RoadForceEditField.Value;
            vehicleParameter.cd = app.CDEditField.Value;
            vehicleParameter.area =app.FrontAream2EditField.Value ;
            vehicleParameter.weight =app.WeightKgEditField.Value ;
            vehicleParameter.payload = app.PayloadKgEditField.Value;
            vehicleParameter.overheadpower =app.OverheadpowerwEditField.Value ;
            
            simulateVsim(app,files,cellParameter,moduleParameter,packParameter,motorParameter,wheelParameter,drivetrainParameter,vehicleParameter)


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            log = sprintf('Getting ocv data for the selected model');
            DisplayLogCellSim(app,log)
            
            soc = app.SCOEditField.Value;
            temp = app.TemperatureCEditField.Value;
            model = app.ModelnameEditField.Value;
            
            getocv(app,soc,temp,model)
            
            
        end

        % Button pushed function: SimulateasPCMButton
        function SimulateasPCMButtonPushed(app, event)
            %simPCMGUI(Ns,Np,maxtime,t0,rt,randsoc,randcap,randres)
            log = sprintf('Simulating PCM for given parameters');
            DisplayLogCellSim(app,log)
            Ns= app.NumberofcellsinSeriesEditField.Value;
            Np=app.NumberofcellsinParallellEditField.Value;
            maxtime=app.SimulationruntimesEditField.Value;
            t0=app.PackResttimesEditField.Value;
            rt=app.TabresistanceEditField.Value;
            randsoc=app.RandomiseinitialSOCCheckBox.Value;
            randcap=app.RandomiseinitialcellresistanceCheckBox.Value  ;
            randres=app.RandomiseinitialcellcapacitanceCheckBox.Value;
            simPCM(app,Ns,Np,maxtime,t0,rt,randsoc,randcap,randres)
        end

        % Button pushed function: SimulateasSCMButton
        function SimulateasSCMButtonPushed(app, event)
            log = sprintf('Simulating SCM for given parameters');
            DisplayLogCellSim(app,log)
            Ns= app.NumberofcellsinSeriesEditField.Value;
            Np=app.NumberofcellsinParallellEditField.Value;
            maxtime=app.SimulationruntimesEditField.Value;
            t0=app.PackResttimesEditField.Value;
            rt=app.TabresistanceEditField.Value;
            randsoc=app.RandomiseinitialSOCCheckBox.Value;
            randcap=app.RandomiseinitialcellresistanceCheckBox.Value  ;
            randres=app.RandomiseinitialcellcapacitanceCheckBox.Value;
            simSCM(app,Ns,Np,maxtime,t0,rt,randsoc,randcap,randres)
        end

        % Button pushed function: SimulateDyn
        function SimulateDynButtonPushed(app, event)
            log = sprintf('Calculating Dynamic model parameters');
            DisplayLogDyn(app,log)
            a = app.CellIDDyn.Value;
            b=erase(a,{'[',']'});
            cellIDs=split(b,{' ',','});
            
            a = app.tempDyn.Value;
            temps=str2double(strsplit(a,','));
            
            numpoles =  app.NumberofRCpairsEditField.Value;
            
            a = app.mag.Value;
            c = split(a,{' ',';'});
            for i = 1:length(c)
                c{i} = str2double(strsplit(c{i},','));
            end
            mags = c';
            %setupGUIDyn(cellIDs,temps,mags,numpoles)
            GUIDyn(app,cellIDs,temps,mags,numpoles)


        end

        % Button pushed function: SimulateLoadButton
        function SimulateLoadButtonPushed(app, event)
            simload(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StaticTab = uitab(app.TabGroup);
            app.StaticTab.Title = 'Static';
            app.EnterCellIDsseparatedbyEditFieldLabel = uilabel(app.StaticTab);
            app.EnterCellIDsseparatedbyEditFieldLabel.Text = 'Enter Cell IDs separated by '','' ';
            app.CellID = uieditfield(app.StaticTab, 'text');
            app.CellID.Value = 'A123,SAM';
            app.EntertestedtemperaturesseparatedbyEditFieldLabel = uilabel(app.StaticTab);
            app.EntertestedtemperaturesseparatedbyEditFieldLabel.Text = 'Enter tested temperatures separated by '',''';
            app.temp = uieditfield(app.StaticTab, 'text');
            app.temp.Value = '25,35,-25';
            app.EnterminVoltagesforeachcellseparatedbyEditFieldLabel = uilabel(app.StaticTab);
            app.EnterminVoltagesforeachcellseparatedbyEditFieldLabel.Text = 'Enter min Voltages for each cell separated by '',''';
            app.minV = uieditfield(app.StaticTab, 'text');
            app.minV.Value = '2,2.5';
            app.EntermaxVoltagesforeachcellseparatedbyEditFieldLabel = uilabel(app.StaticTab);
            app.EntermaxVoltagesforeachcellseparatedbyEditFieldLabel.Text = 'Enter max Voltages for each cell separated by '',''';
            app.maxV = uieditfield(app.StaticTab, 'text');
            app.maxV.Value = '3.75,4.25';
            app.Simulate = uibutton(app.StaticTab, 'push');
            app.Simulate.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.Simulate.Text = 'Calculate Parameters';
            app.LogsTextAreaLabel = uilabel(app.StaticTab);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.Logs = uitextarea(app.StaticTab);
            app.Logs.Value = {'Enter required parameters and then press the calculate button.'; ''};
            app.Save = uicheckbox(app.StaticTab);
            app.Save.Text = 'Save data';
            app.Save.Value = true;
            app.DynamicTab = uitab(app.TabGroup);
            app.DynamicTab.Title = 'Dynamic';
            app.EnterCellIDsseparatedbyEditFieldLabel_2 = uilabel(app.DynamicTab);
            app.EnterCellIDsseparatedbyEditFieldLabel_2.Text = 'Enter Cell IDs separated by '','' ';
            app.CellIDDyn = uieditfield(app.DynamicTab, 'text');
            app.CellIDDyn.Value = 'A123,ATL';
            app.EntertestedtemperaturesseparatedbyEditFieldLabel_2 = uilabel(app.DynamicTab);
            app.EntertestedtemperaturesseparatedbyEditFieldLabel_2.Text = 'Enter tested temperatures separated by '',''';
            app.tempDyn = uieditfield(app.DynamicTab, 'text');
            app.tempDyn.Value = '-5,25,35,45';
            app.EditFieldLabel = uilabel(app.DynamicTab);
            app.EditFieldLabel.Text = {'Enter maximum C rateing used for each test temperature (seperated by '','') '; 'and for each Cell (seperated by '';'' )'};
            app.mag = uieditfield(app.DynamicTab, 'text');
            app.mag.Value = '30,50,50,50;15,40,50,50';
            app.NumberofRCpairsEditFieldLabel = uilabel(app.DynamicTab);
            app.NumberofRCpairsEditFieldLabel.Text = 'Number of RC pairs';
            app.NumberofRCpairsEditField = uieditfield(app.DynamicTab, 'numeric');
            app.NumberofRCpairsEditField.Value = 1;
            app.LogsTextAreaLabel_5 = uilabel(app.DynamicTab);
            app.LogsTextAreaLabel_5.Text = 'Logs';
            app.LogsDyn = uitextarea(app.DynamicTab);
            app.LogsDyn.Value = {'Enter required parameters and then press the calculate button.'; ''};
            app.SimulateDyn = uibutton(app.DynamicTab, 'push');
            app.SimulateDyn.ButtonPushedFcn = createCallbackFcn(app, @SimulateDynButtonPushed, true);
            app.SimulateDyn.Text = 'Calculate Parameters';
            app.SaveDyn = uicheckbox(app.DynamicTab);
            app.SaveDyn.Text = 'Save data';
            app.SaveDyn.Value = true;
            app.EVSimTab = uitab(app.TabGroup);
            app.EVSimTab.Title = 'EVSim';
            app.LogsTextAreaLabel_3 = uilabel(app.EVSimTab);
            app.LogsTextAreaLabel_3.Text = 'Logs';
            app.EVLogs = uitextarea(app.EVSimTab);
            app.EVLogs.Value = {'Enter required parameters and then press the simulate button.'; 'The default  parameters roughly describe a first-generation GM/Chevy Volt during  charge-depletion mode '; ''};
            app.DriveCycleFilesseparatedbyEditFieldLabel = uilabel(app.EVSimTab);
            app.DriveCycleFilesseparatedbyEditFieldLabel.Text = 'Drive Cycle Files (separated by ,)';
            app.files = uieditfield(app.EVSimTab, 'text');
            app.files.Value = 'nycc.txt,udds.txt,us06.txt,hwfet.txt,kt.txt';
            app.CellParametersLabel = uilabel(app.EVSimTab);
            app.CellParametersLabel.Text = 'Cell Parameters';
            app.ModuleParametersLabel = uilabel(app.EVSimTab);
            app.ModuleParametersLabel.Text = 'Module Parameters';
            app.PackParametersLabel = uilabel(app.EVSimTab);
            app.PackParametersLabel.Text = 'Pack Parameters';
            app.MotorParametersLabel = uilabel(app.EVSimTab);
            app.MotorParametersLabel.Text = 'Motor Parameters';
            app.WheelParametersLabel = uilabel(app.EVSimTab);
            app.WheelParametersLabel.Text = 'Wheel Parameters';
            app.DrivetrainParametersLabel = uilabel(app.EVSimTab);
            app.DrivetrainParametersLabel.Text = 'Drive train Parameters';
            app.VehicleParametersLabel = uilabel(app.EVSimTab);
            app.VehicleParametersLabel.Text = 'Vehicle Parameters';
            app.CapacityAhEditFieldLabel = uilabel(app.EVSimTab);
            app.CapacityAhEditFieldLabel.Text = 'Capacity (Ah)';
            app.CapacityAhEditField = uieditfield(app.EVSimTab, 'numeric');
            app.CapacityAhEditField.Value = 15;
            app.WeightgEditFieldLabel = uilabel(app.EVSimTab);
            app.WeightgEditFieldLabel.Text = 'Weight (g)';
            app.WeightgEditField = uieditfield(app.EVSimTab, 'numeric');
            app.WeightgEditField.Value = 450;
            app.VmaxEditFieldLabel = uilabel(app.EVSimTab);
            app.VmaxEditFieldLabel.Text = 'Vmax';
            app.VmaxEditField = uieditfield(app.EVSimTab, 'numeric');
            app.VmaxEditField.Value = 4.2;
            app.VnomEditFieldLabel = uilabel(app.EVSimTab);
            app.VnomEditFieldLabel.Text = 'Vnom';
            app.VnomEditField = uieditfield(app.EVSimTab, 'numeric');
            app.VnomEditField.Value = 3.8;
            app.VminEditFieldLabel = uilabel(app.EVSimTab);
            app.VminEditFieldLabel.Text = 'Vmin';
            app.VminEditField = uieditfield(app.EVSimTab, 'numeric');
            app.VminEditField.Value = 3;
            app.NoParallelEditFieldLabel = uilabel(app.EVSimTab);
            app.NoParallelEditFieldLabel.Text = 'No. Parallel';
            app.NoParallelEditField = uieditfield(app.EVSimTab, 'numeric');
            app.NoParallelEditField.Value = 3;
            app.NoSeriesEditFieldLabel = uilabel(app.EVSimTab);
            app.NoSeriesEditFieldLabel.Text = 'No. Series';
            app.NoSeriesEditField = uieditfield(app.EVSimTab, 'numeric');
            app.NoSeriesEditField.Value = 8;
            app.OverHeadEditFieldLabel = uilabel(app.EVSimTab);
            app.OverHeadEditFieldLabel.Text = 'Over Head';
            app.OverHeadEditField = uieditfield(app.EVSimTab, 'numeric');
            app.OverHeadEditField.Value = 0.08;
            app.NoSeriesEditField_2Label = uilabel(app.EVSimTab);
            app.NoSeriesEditField_2Label.Text = 'No. Series';
            app.NoSeriesEditField_2 = uieditfield(app.EVSimTab, 'numeric');
            app.NoSeriesEditField_2.Value = 12;
            app.OverHeadEditField_2Label = uilabel(app.EVSimTab);
            app.OverHeadEditField_2Label.Text = 'Over Head';
            app.OverHeadEditField_2 = uieditfield(app.EVSimTab, 'numeric');
            app.OverHeadEditField_2.Value = 0.1;
            app.FullSOCEditFieldLabel = uilabel(app.EVSimTab);
            app.FullSOCEditFieldLabel.Text = 'Full SOC';
            app.FullSOCEditField = uieditfield(app.EVSimTab, 'numeric');
            app.FullSOCEditField.Value = 75;
            app.EmptySOCEditFieldLabel = uilabel(app.EVSimTab);
            app.EmptySOCEditFieldLabel.Text = 'Empty SOC';
            app.EmptySOCEditField = uieditfield(app.EVSimTab, 'numeric');
            app.EmptySOCEditField.Value = 25;
            app.EfficiencyEditFieldLabel = uilabel(app.EVSimTab);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.EfficiencyEditField = uieditfield(app.EVSimTab, 'numeric');
            app.EfficiencyEditField.Value = 0.96;
            app.LmaxNmEditFieldLabel = uilabel(app.EVSimTab);
            app.LmaxNmEditFieldLabel.Text = 'Lmax (Nm)';
            app.LmaxNmEditField = uieditfield(app.EVSimTab, 'numeric');
            app.LmaxNmEditField.Value = 275;
            app.RatedRPMEditFieldLabel = uilabel(app.EVSimTab);
            app.RatedRPMEditFieldLabel.Text = 'Rated RPM';
            app.RatedRPMEditField = uieditfield(app.EVSimTab, 'numeric');
            app.RatedRPMEditField.Value = 4000;
            app.maxRPMEditFieldLabel = uilabel(app.EVSimTab);
            app.maxRPMEditFieldLabel.Text = 'max RPM';
            app.maxRPMEditField = uieditfield(app.EVSimTab, 'numeric');
            app.maxRPMEditField.ValueDisplayFormat = '%.0f';
            app.maxRPMEditField.Value = 12000;
            app.EfficiencyEditField_2Label = uilabel(app.EVSimTab);
            app.EfficiencyEditField_2Label.Text = 'Efficiency';
            app.EfficiencyEditField_2 = uieditfield(app.EVSimTab, 'numeric');
            app.EfficiencyEditField_2.Value = 0.95;
            app.InertiaKgm2Label_2 = uilabel(app.EVSimTab);
            app.InertiaKgm2Label_2.Text = 'Inertia (Kg/m2)';
            app.InertiaKgm2EditField = uieditfield(app.EVSimTab, 'numeric');
            app.InertiaKgm2EditField.Value = 0.2;
            app.RadiusmEditFieldLabel = uilabel(app.EVSimTab);
            app.RadiusmEditFieldLabel.Text = 'Radius (m)';
            app.RadiusmEditField = uieditfield(app.EVSimTab, 'numeric');
            app.RadiusmEditField.Value = 0.35;
            app.InertiaKgm2Label = uilabel(app.EVSimTab);
            app.InertiaKgm2Label.Text = 'Inertia (Kg/m2)';
            app.InertiaKgm2EditField_2 = uieditfield(app.EVSimTab, 'numeric');
            app.InertiaKgm2EditField_2.Value = 8;
            app.RollCoeffEditFieldLabel = uilabel(app.EVSimTab);
            app.RollCoeffEditFieldLabel.Text = 'Roll Coeff';
            app.RollCoeffEditField = uieditfield(app.EVSimTab, 'numeric');
            app.RollCoeffEditField.Value = 0.0111;
            app.InverterEfficiencyEditFieldLabel = uilabel(app.EVSimTab);
            app.InverterEfficiencyEditFieldLabel.Text = 'Inverter Efficiency';
            app.InverterEfficiencyEditField = uieditfield(app.EVSimTab, 'numeric');
            app.InverterEfficiencyEditField.Value = 0.94;
            app.FractionalRegenTorqueEditFieldLabel = uilabel(app.EVSimTab);
            app.FractionalRegenTorqueEditFieldLabel.Text = 'Fractional Regen Torque';
            app.FractionalRegenTorqueEditField = uieditfield(app.EVSimTab, 'numeric');
            app.FractionalRegenTorqueEditField.Value = 0.9;
            app.GearRatioEditFieldLabel = uilabel(app.EVSimTab);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio';
            app.GearRatioEditField = uieditfield(app.EVSimTab, 'numeric');
            app.GearRatioEditField.Value = 12;
            app.GearInertiaKgm2EditFieldLabel = uilabel(app.EVSimTab);
            app.GearInertiaKgm2EditFieldLabel.Text = 'Gear Inertia (Kg/m2)';
            app.GearInertiaKgm2EditField = uieditfield(app.EVSimTab, 'numeric');
            app.GearInertiaKgm2EditField.Value = 0.05;
            app.GearEfficiencyEditFieldLabel = uilabel(app.EVSimTab);
            app.GearEfficiencyEditFieldLabel.Text = 'Gear Efficiency';
            app.GearEfficiencyEditField = uieditfield(app.EVSimTab, 'numeric');
            app.GearEfficiencyEditField.Value = 0.97;
            app.NoofWheelsEditFieldLabel = uilabel(app.EVSimTab);
            app.NoofWheelsEditFieldLabel.Text = 'No. of Wheels';
            app.NoofWheelsEditField = uieditfield(app.EVSimTab, 'numeric');
            app.NoofWheelsEditField.Value = 4;
            app.RoadForceEditFieldLabel = uilabel(app.EVSimTab);
            app.RoadForceEditFieldLabel.Text = 'Road Force ';
            app.RoadForceEditField = uieditfield(app.EVSimTab, 'numeric');
            app.CDEditFieldLabel = uilabel(app.EVSimTab);
            app.CDEditFieldLabel.Text = 'CD';
            app.CDEditField = uieditfield(app.EVSimTab, 'numeric');
            app.CDEditField.Value = 0.22;
            app.FrontAream2Label = uilabel(app.EVSimTab);
            app.FrontAream2Label.Text = 'Front  Area (m^2)';
            app.FrontAream2EditField = uieditfield(app.EVSimTab, 'numeric');
            app.FrontAream2EditField.Value = 1.84;
            app.WeightKgEditFieldLabel = uilabel(app.EVSimTab);
            app.WeightKgEditFieldLabel.Text = 'Weight (Kg)';
            app.WeightKgEditField = uieditfield(app.EVSimTab, 'numeric');
            app.WeightKgEditField.Value = 1425;
            app.PayloadKgEditFieldLabel = uilabel(app.EVSimTab);
            app.PayloadKgEditFieldLabel.Text = 'Payload (Kg)';
            app.PayloadKgEditField = uieditfield(app.EVSimTab, 'numeric');
            app.PayloadKgEditField.Value = 75;
            app.OverheadpowerwEditFieldLabel = uilabel(app.EVSimTab);
            app.OverheadpowerwEditFieldLabel.Text = 'Over head power (w)';
            app.OverheadpowerwEditField = uieditfield(app.EVSimTab, 'numeric');
            app.OverheadpowerwEditField.Value = 200;
            app.EVSimulate = uibutton(app.EVSimTab, 'push');
            app.EVSimulate.ButtonPushedFcn = createCallbackFcn(app, @EVSimulateButtonPushed, true);
            app.EVSimulate.Text = 'Simulate';
            app.CellSimTab = uitab(app.TabGroup);
            app.CellSimTab.Title = 'CellSim';
            app.LogsTextAreaLabel_4 = uilabel(app.CellSimTab);
            app.LogsTextAreaLabel_4.Text = 'Logs';
            app.CellLogs = uitextarea(app.CellSimTab);
            app.CellLogs.Value = {'Enter required parameters and then press the simulate button.'; ''};
            app.OCVfromTemperatureandSOCLabel = uilabel(app.CellSimTab);
            app.OCVfromTemperatureandSOCLabel.Text = 'OCV from Temperature and SOC';
            app.TemperatureCEditFieldLabel = uilabel(app.CellSimTab);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (C)';
            app.TemperatureCEditField = uieditfield(app.CellSimTab, 'numeric');
            app.TemperatureCEditField.Value = 25;
            app.SCOEditFieldLabel = uilabel(app.CellSimTab);
            app.SCOEditFieldLabel.Text = 'SCO';
            app.SCOEditField = uieditfield(app.CellSimTab, 'numeric');
            app.SCOEditField.Value = 0.5;
            app.ModelnameEditFieldLabel = uilabel(app.CellSimTab);
            app.ModelnameEditFieldLabel.Text = 'Model name';
            app.ModelnameEditField = uieditfield(app.CellSimTab, 'text');
            app.ModelnameEditField.Value = 'E2model';
            app.CalculateButton = uibutton(app.CellSimTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SCMPCMsimulatorLabel = uilabel(app.CellSimTab);
            app.SCMPCMsimulatorLabel.Text = 'SCM, PCM simulator';
            app.NumberofcellsinSeriesEditFieldLabel = uilabel(app.CellSimTab);
            app.NumberofcellsinSeriesEditFieldLabel.Text = 'Number of cells in Series';
            app.NumberofcellsinSeriesEditField = uieditfield(app.CellSimTab, 'numeric');
            app.NumberofcellsinSeriesEditField.Value = 3;
            app.NumberofcellsinParallellEditFieldLabel = uilabel(app.CellSimTab);
            app.NumberofcellsinParallellEditFieldLabel.Text = 'Number of cells in Parallell';
            app.NumberofcellsinParallellEditField = uieditfield(app.CellSimTab, 'numeric');
            app.NumberofcellsinParallellEditField.Value = 3;
            app.SimulationruntimesEditFieldLabel = uilabel(app.CellSimTab);
            app.SimulationruntimesEditFieldLabel.Text = 'Simulation runtime (s)';
            app.SimulationruntimesEditField = uieditfield(app.CellSimTab, 'numeric');
            app.SimulationruntimesEditField.Value = 4800;
            app.PackResttimesEditFieldLabel = uilabel(app.CellSimTab);
            app.PackResttimesEditFieldLabel.Text = 'Pack Rest time (s)';
            app.PackResttimesEditField = uieditfield(app.CellSimTab, 'numeric');
            app.PackResttimesEditField.Value = 2700;
            app.TabresistanceEditFieldLabel = uilabel(app.CellSimTab);
            app.TabresistanceEditFieldLabel.Text = 'Tab resistance';
            app.TabresistanceEditField = uieditfield(app.CellSimTab, 'numeric');
            app.TabresistanceEditField.Value = 0.000125;
            app.RandomiseinitialSOCCheckBox = uicheckbox(app.CellSimTab);
            app.RandomiseinitialSOCCheckBox.Text = 'Randomise initial SOC';
            app.RandomiseinitialcellresistanceCheckBox = uicheckbox(app.CellSimTab);
            app.RandomiseinitialcellresistanceCheckBox.Text = 'Randomise initial cell resistance';
            app.RandomiseinitialcellcapacitanceCheckBox = uicheckbox(app.CellSimTab);
            app.RandomiseinitialcellcapacitanceCheckBox.Text = 'Randomise initial cell capacitance';
            app.SimulateasPCMButton = uibutton(app.CellSimTab, 'push');
            app.SimulateasPCMButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateasPCMButtonPushed, true);
            app.SimulateasPCMButton.Text = 'Simulate as PCM';
            app.SimulateasSCMButton = uibutton(app.CellSimTab, 'push');
            app.SimulateasSCMButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateasSCMButtonPushed, true);
            app.SimulateasSCMButton.Text = 'Simulate as SCM';
            app.LoadsimulatorLabel = uilabel(app.CellSimTab);
            app.LoadsimulatorLabel.Text = 'Load simulator';
            app.ItwhereisdischargeEditFieldLabel = uilabel(app.CellSimTab);
            app.ItwhereisdischargeEditFieldLabel.Enable = 'off';
            app.ItwhereisdischargeEditFieldLabel.Text = 'I(t) , where (+) is discharge';
            app.ItwhereisdischargeEditField = uieditfield(app.CellSimTab, 'text');
            app.ItwhereisdischargeEditField.Editable = 'off';
            app.ItwhereisdischargeEditField.Enable = 'off';
            app.TemperatureCEditField_2Label = uilabel(app.CellSimTab);
            app.TemperatureCEditField_2Label.Text = 'Temperature (C)';
            app.TemperatureCEditField_2 = uieditfield(app.CellSimTab, 'numeric');
            app.TemperatureCEditField_2.Value = 25;
            app.SamplingTimesEditFieldLabel = uilabel(app.CellSimTab);
            app.SamplingTimesEditFieldLabel.Text = 'Sampling Time (s)';
            app.SamplingTimesEditField = uieditfield(app.CellSimTab, 'numeric');
            app.SamplingTimesEditField.Value = 1;
            app.ModelnameEditField_2Label = uilabel(app.CellSimTab);
            app.ModelnameEditField_2Label.Text = 'Model name';
            app.ModelnameEditField_2 = uieditfield(app.CellSimTab, 'text');
            app.ModelnameEditField_2.Value = 'E2model';
            app.RawtestfilenameEditFieldLabel = uilabel(app.CellSimTab);
            app.RawtestfilenameEditFieldLabel.Text = 'Raw test file name';
            app.RawtestfilenameEditField = uieditfield(app.CellSimTab, 'text');
            app.RawtestfilenameEditField.Value = 'E2_DYN_P25';
            app.Z0EditFieldLabel = uilabel(app.CellSimTab);
            app.Z0EditFieldLabel.Text = 'Z0';
            app.Z0EditField = uieditfield(app.CellSimTab, 'numeric');
            app.Z0EditField.Value = 1;
            app.InitialResCurrentEditFieldLabel = uilabel(app.CellSimTab);
            app.InitialResCurrentEditFieldLabel.Text = 'Initial Res. Current';
            app.InitialResCurrentEditField = uieditfield(app.CellSimTab, 'numeric');
            app.InitialHysteresisstateEditFieldLabel = uilabel(app.CellSimTab);
            app.InitialHysteresisstateEditFieldLabel.Text = 'Initial Hysteresis state';
            app.InitialHysteresisstateEditField = uieditfield(app.CellSimTab, 'numeric');
            app.SimulateLoadButton = uibutton(app.CellSimTab, 'push');
            app.SimulateLoadButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateLoadButtonPushed, true);
            app.SimulateLoadButton.Text = 'Simulate Load';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AbouttheappLabel = uilabel(app.AboutTab);
            app.AbouttheappLabel.Text = 'About the app';
            app.TextArea = uitextarea(app.AboutTab);
            app.TextArea.Value = {'The GUI app was built by Ashwin Kumar an Internship student at Kaynes Technologies, the code to proces the data was obtailed from   Plett, Gregory L.''s suppliment to the text book : "Battery  Management Systems, Volume I, Battery Modeling,'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Handler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end