classdef processOCVGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        CalculateTab       matlab.ui.container.Tab
        EnterCellIDsseparatedbyEditFieldLabel  matlab.ui.control.Label
        CellID             matlab.ui.control.EditField
        EntertestedtemperaturesseparatedbyEditFieldLabel  matlab.ui.control.Label
        temp               matlab.ui.control.EditField
        EnterminVoltagesforeachcellseparatedbyEditFieldLabel  matlab.ui.control.Label
        minV               matlab.ui.control.EditField
        EntermaxVoltagesforeachcellseparatedbyEditFieldLabel  matlab.ui.control.Label
        maxV               matlab.ui.control.EditField
        Simulate           matlab.ui.control.Button
        LogsTextAreaLabel  matlab.ui.control.Label
        Logs               matlab.ui.control.TextArea
        Save               matlab.ui.control.CheckBox
        EntertestedtemperaturesseparatedbyEditFieldLabel_2  matlab.ui.control.Label
        EntertestedtemperaturesseparatedbyEditField  matlab.ui.control.NumericEditField
        VisualiseTab       matlab.ui.container.Tab
        AboutTab           matlab.ui.container.Tab
        AbouttheappLabel   matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
        TextArea_2         matlab.ui.control.TextArea
        EditFieldLabel     matlab.ui.control.Label
        EditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        iTextBox = 0;
    end
    
    methods (Access = public)
        
        function DisplayLog(app,message)
        app.iTextBox = app.iTextBox + 1;
       % app.Logs.Value(app.iTextBox) = {message};
       %app.Logs.Value(app.iTextBox) = sprintf('%s\n',message);
       %app.Logs.Value = [app.Logs.Value;'\n'];
       app.Logs.Value = [app.Logs.Value;message];
       
        end
    end
    
    methods (Access = private)
        
        function results = simulate(~,cellIDs,temps,minV,maxV,saveornot)
            result = fnRunProcessOCV(cellIDs,temps,minV,maxV,saveornot);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Simulate
        function SimulateButtonPushed(app, event)
  
            a = app.CellID.Value;
            b=erase(a,{'[',']'});
            cellIDs=split(b,{' ',','});
            
            a = app.temp.Value;
            b=erase(a,{'[',']'});
            temps=(split(b,[' ',',']));
            
            
            a = app.minV.Value;
            b=erase(a,{'[',']'});
            minVs=(split(b,{' ',','}));
            
            
            a = app.maxV.Value;
            b=erase(a,{'[',']'});
            maxVs=(split(b,{' ',','}));
      
            a=0;
            
            saveornot = app.Save.Value;
            
            log = sprintf('you have entered cell IDs as');
            DisplayLog(app,log)
            log = sprintf('  %s ',cellIDs{:});
            DisplayLog(app,log)
            
            log = sprintf('you have entered temp values as');
            DisplayLog(app,log)
            log = sprintf('  %s ',temps{:});
            DisplayLog(app,log)
            
            log = sprintf('you have entered min voltages as');
            DisplayLog(app,log)
            log = sprintf('  %s ',minVs{:});
            DisplayLog(app,log)
            
            log = sprintf('you have entered max voltages as');
            DisplayLog(app,log)
            log = sprintf('  %s ',maxVs{:});
            DisplayLog(app,log)


            if( max(size(cellIDs)) ~= max(size(minVs))  || max(size(maxVs)) ~= max(size(minVs)) )
                log = sprintf('you have entered incorrect number of data( no. of Cell IDs , Min Voltages and Max Voltages should be same)');
                DisplayLog(app,log)
            else
                %%simulate
                minVs = str2double(minVs);
                minVs = str2double(minVs);
                temps= str2double(temps);
                ftemps = {};
                for i = 1:length(cellIDs)
                    ftemps{i} = temps;
                end
                
                simulate(app,cellIDs,ftemps,minVs,maxVs,saveornot)
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.EnterCellIDsseparatedbyEditFieldLabel = uilabel(app.CalculateTab);
            app.EnterCellIDsseparatedbyEditFieldLabel.Text = 'Enter Cell IDs separated by '','' ';
            app.CellID = uieditfield(app.CalculateTab, 'text');
            app.EntertestedtemperaturesseparatedbyEditFieldLabel = uilabel(app.CalculateTab);
            app.EntertestedtemperaturesseparatedbyEditFieldLabel.Text = 'Enter tested temperatures separated by '',''';
            app.temp = uieditfield(app.CalculateTab, 'text');
            app.EnterminVoltagesforeachcellseparatedbyEditFieldLabel = uilabel(app.CalculateTab);
            app.EnterminVoltagesforeachcellseparatedbyEditFieldLabel.Text = 'Enter min Voltages for each cell separated by '',''';
            app.minV = uieditfield(app.CalculateTab, 'text');
            app.EntermaxVoltagesforeachcellseparatedbyEditFieldLabel = uilabel(app.CalculateTab);
            app.EntermaxVoltagesforeachcellseparatedbyEditFieldLabel.Text = 'Enter max Voltages for each cell separated by '',''';
            app.maxV = uieditfield(app.CalculateTab, 'text');
            app.Simulate = uibutton(app.CalculateTab, 'push');
            app.Simulate.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.Simulate.Text = 'Simulate';
            app.LogsTextAreaLabel = uilabel(app.CalculateTab);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.Logs = uitextarea(app.CalculateTab);
            app.Save = uicheckbox(app.CalculateTab);
            app.Save.Text = 'Save data';
            app.Save.Value = true;
            app.EntertestedtemperaturesseparatedbyEditFieldLabel_2 = uilabel(app.CalculateTab);
            app.EntertestedtemperaturesseparatedbyEditFieldLabel_2.Text = 'Enter tested temperatures separated by '',''';
            app.EntertestedtemperaturesseparatedbyEditField = uieditfield(app.CalculateTab, 'numeric');
            app.VisualiseTab = uitab(app.TabGroup);
            app.VisualiseTab.Title = 'Visualise';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AbouttheappLabel = uilabel(app.AboutTab);
            app.AbouttheappLabel.Text = 'About the app';
            app.TextArea = uitextarea(app.AboutTab);
            app.TextArea.Value = {'The app was built by Ashwin Kumar an Internship student at Kaynes, the code to proces the data was obtailed from   Plett, Gregory L.''s suppliment to the text book : "Battery  Management Systems, Volume I, Battery Modeling,'};
            app.TextArea_2 = uitextarea(app.AboutTab);
            app.TextArea_2.Value = {'Todo:'; 'repair the datatypes'; 'Fill this doc'; 'add visualise part'};
            app.EditFieldLabel = uilabel(app.AboutTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.AboutTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = processOCVGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end